classdef HARF_drawing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TubularantiresonanthollowcorefibersdrawingsimulationUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DirectcomputationTab            matlab.ui.container.Tab
        OutputPanel_2                   matlab.ui.container.Panel
        SaveresultsButton               matlab.ui.control.Button
        dc0Dc0Label_2                   matlab.ui.control.Label
        dcfDcfLabel_2                   matlab.ui.control.Label
        VfLabel                         matlab.ui.control.Label
        maxRcRcontactLabel              matlab.ui.control.Label
        tfLabel                         matlab.ui.control.Label
        DcoreLabel                      matlab.ui.control.Label
        dcfLabel                        matlab.ui.control.Label
        DcfLabel                        matlab.ui.control.Label
        dfLabel                         matlab.ui.control.Label
        DfLabel                         matlab.ui.control.Label
        InputPanel_2                    matlab.ui.container.Panel
        FurnacelengthmEditField         matlab.ui.control.NumericEditField
        FurnacelengthmEditFieldLabel    matlab.ui.control.Label
        DeltaPEditField                 matlab.ui.control.NumericEditField
        PcapmbarLabel                   matlab.ui.control.Label
        TmaxCEditField                  matlab.ui.control.NumericEditField
        TmaxCEditFieldLabel             matlab.ui.control.Label
        NumberofcapillariesEditField    matlab.ui.control.NumericEditField
        NumberofcapillariesEditFieldLabel  matlab.ui.control.Label
        DrawtensiongEditField           matlab.ui.control.NumericEditField
        DrawtensiongEditFieldLabel      matlab.ui.control.Label
        LmEditField                     matlab.ui.control.NumericEditField
        LmEditFieldLabel                matlab.ui.control.Label
        dc0mmEditField                  matlab.ui.control.NumericEditField
        dc0mmEditFieldLabel             matlab.ui.control.Label
        Dc0mmEditField                  matlab.ui.control.NumericEditField
        Dc0mmEditFieldLabel             matlab.ui.control.Label
        d0mmEditField                   matlab.ui.control.NumericEditField
        d0mmEditFieldLabel              matlab.ui.control.Label
        D0mmEditField                   matlab.ui.control.NumericEditField
        D0mmEditFieldLabel              matlab.ui.control.Label
        FeedspeedmmminEditField         matlab.ui.control.NumericEditField
        FeedspeedmmminEditFieldLabel    matlab.ui.control.Label
        PcoremBarEditField              matlab.ui.control.NumericEditField
        PcoremBarEditFieldLabel         matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        FurnacetemperatureprofileEditField  matlab.ui.control.EditField
        FurnacetemperatureprofileEditFieldLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        zmSlider                        matlab.ui.control.Slider
        zmSliderLabel                   matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        GrandeurreprsenterDropDown      matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        OptimizationTab                 matlab.ui.container.Tab
        Button_2                        matlab.ui.control.Button
        FurnacetemperatureprofileEditField_2  matlab.ui.control.EditField
        FurnacetemperatureprofileEditField_2Label  matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        ApproximatevaluesPanel          matlab.ui.container.Panel
        DeltaPmbarLabel_2               matlab.ui.control.Label
        OptimDeltaPmaxEditField         matlab.ui.control.NumericEditField
        maxLabel                        matlab.ui.control.Label
        OptimDeltaPminEditField         matlab.ui.control.NumericEditField
        minEditFieldLabel_3             matlab.ui.control.Label
        OptimPcoremaxEditField          matlab.ui.control.NumericEditField
        maxEditFieldLabel_2             matlab.ui.control.Label
        OptimPcoreminEditField          matlab.ui.control.NumericEditField
        minEditFieldLabel_2             matlab.ui.control.Label
        PcorembarLabel                  matlab.ui.control.Label
        OptimTmaxmaxEditField           matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        OptimTmaxminEditField           matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        TmaxCLabel                      matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        OutputPanel                     matlab.ui.container.Panel
        SaveresultsButton_2             matlab.ui.control.Button
        OptimTmaxLabel                  matlab.ui.control.Label
        OptimPcoreLabel                 matlab.ui.control.Label
        OptimdpLabel                    matlab.ui.control.Label
        OptimmaxLabel                   matlab.ui.control.Label
        GrandeurreprsenterDropDown_2    matlab.ui.control.DropDown
        zmSlider_2                      matlab.ui.control.Slider
        zmSlider_2Label                 matlab.ui.control.Label
        ObjectivePanel                  matlab.ui.container.Panel
        dcfDcfLabel                     matlab.ui.control.Label
        OptimVfLabel                    matlab.ui.control.Label
        OptimdcfLabel                   matlab.ui.control.Label
        OptimdfLabel                    matlab.ui.control.Label
        OptimDfLabel                    matlab.ui.control.Label
        OptimDcoreEditField             matlab.ui.control.NumericEditField
        DcoremEditFieldLabel            matlab.ui.control.Label
        OptimDcfEditField               matlab.ui.control.NumericEditField
        DcfmLabel                       matlab.ui.control.Label
        OptimtfEditField                matlab.ui.control.NumericEditField
        tfmLabel                        matlab.ui.control.Label
        InputPanel                      matlab.ui.container.Panel
        FurnacelengthmEditField_2       matlab.ui.control.NumericEditField
        FurnacelengthmEditField_2Label  matlab.ui.control.Label
        dc0Dc0Label                     matlab.ui.control.Label
        OptimNbEditField                matlab.ui.control.NumericEditField
        NumberofcapillariesLabel        matlab.ui.control.Label
        OptimLEditField                 matlab.ui.control.NumericEditField
        LmLabel                         matlab.ui.control.Label
        Optimdc0EditField               matlab.ui.control.NumericEditField
        dc0mmLabel                      matlab.ui.control.Label
        OptimDc0EditField               matlab.ui.control.NumericEditField
        Dc0mmLabel                      matlab.ui.control.Label
        Optimd0EditField                matlab.ui.control.NumericEditField
        d0mmLabel                       matlab.ui.control.Label
        OptimD0EditField                matlab.ui.control.NumericEditField
        D0mmEditField_2Label            matlab.ui.control.Label
        OptimTensionEditField           matlab.ui.control.NumericEditField
        DrawtensiongLabel               matlab.ui.control.Label
        OptimViEditField                matlab.ui.control.NumericEditField
        FeedspeedmmminLabel             matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        ParametersTab                   matlab.ui.container.Tab
        Button_3                        matlab.ui.control.Button
        Image_3                         matlab.ui.control.Image
        GrandeurreprsenterDropDown_3    matlab.ui.control.DropDown
        ConstantsPanel                  matlab.ui.container.Panel
        ExternaltemperatureCEditField   matlab.ui.control.NumericEditField
        ExternaltemperatureCEditFieldLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        Differenceoftemperaturebetweenthefurnaceandthepreformatz0KLabel  matlab.ui.control.Label
        GravitationalaccelerationmsEditField  matlab.ui.control.NumericEditField
        GravitationalaccelerationmsEditFieldLabel  matlab.ui.control.Label
        NewtonheattransfercoefficientWmKEditField  matlab.ui.control.NumericEditField
        NewtonheattransfercoefficientWmKEditFieldLabel  matlab.ui.control.Label
        StefanBoltzmannconstantWmK4EditField  matlab.ui.control.NumericEditField
        StefanBoltzmannconstantWmK4EditFieldLabel  matlab.ui.control.Label
        MaterialpropertiesFunctionsofTKPanel  matlab.ui.container.Panel
        Volumicemissivity1mEditField    matlab.ui.control.EditField
        Volumicemissivity1mEditFieldLabel  matlab.ui.control.Label
        ViscosityNsmEditField           matlab.ui.control.EditField
        ViscosityNsmEditFieldLabel      matlab.ui.control.Label
        SpecificheatJkgKEditField       matlab.ui.control.EditField
        SpecificheatJkgKEditFieldLabel  matlab.ui.control.Label
        SurfacetensionNmEditField       matlab.ui.control.EditField
        SurfacetensionNmEditFieldLabel  matlab.ui.control.Label
        Volumicmasskgm3EditField        matlab.ui.control.EditField
        Volumicmasskgm3EditFieldLabel   matlab.ui.control.Label
        UIAxes_3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        param % Description
        result % Description
        calcul_fait
        optim
        optim_fait
        paramini
        graph1
        graph2
    end
    
    methods (Access = private)

        function update_optim_objectifs(app)
            Vi = app.OptimViEditField.Value;
            D0=app.OptimD0EditField.Value;
            d0=app.Optimd0EditField.Value;
            Dc0=app.OptimDc0EditField.Value;
            dc0=app.Optimdc0EditField.Value;
            tf=app.OptimtfEditField.Value;
            Dcf=app.OptimDcfEditField.Value;
            Dcore=app.OptimDcoreEditField.Value;
            dcf=Dcf-2*tf;
            df=Dcore+2*Dcf;
            Vf=(Dc0^2-dc0^2)/(Dcf^2-dcf^2)*Vi;
            Df=sqrt((D0^2-d0^2)*Vi/Vf+df^2);
            if(~isnan(dcf))
                app.OptimdcfLabel.Text=strcat('dcf = ',num2str(dcf),' µm');
                app.dcfDcfLabel.Text=strcat('dcf/Dcf = ',num2str(dcf/Dcf));
            end
            if(~isnan(df))
                app.OptimdfLabel.Text=strcat('df = ',num2str(df),' µm');
            end
            if(~isnan(Vf))
                app.OptimVfLabel.Text=strcat('Vf = ',num2str(Vf*1000),' m/min');
            end
            if(~isnan(Df))
                app.OptimDfLabel.Text=strcat('Df = ',num2str(Df),' µm');
            end
        end
    
        function trace(app)
            value = app.GrandeurreprsenterDropDown.Value;
            if (app.calcul_fait)
               if (strcmp(value,'V'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.w*60);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'V (m/min)');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'D'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.R*2*1E6);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'D (µm)');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'d'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.r*2*1E6);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'d (µm)');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'Dc'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.Rc*2*1E6);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'Dc (µm)');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'dc'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.rc*2*1E6);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'dc (µm)');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'t'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.Rc*1E6-app.result.rc*1E6);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'t (µm)');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'Dcore'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.r*2E6-app.result.Rc*4E6);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'Dcore (µm)');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'dc/Dc'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.rc./app.result.Rc);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'dc/Dc');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'Draw tension'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.tau/9.81*1000);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'Tension (g)');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'Temperature'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.T-273,app.result.z,app.param.Tmax*arrayfun(app.param.profil,app.result.z).*(app.result.z<=app.param.Lfour)+app.param.Text*(app.result.z>app.param.Lfour));
                   app.UIAxes.XLim = [0 app.param.L];
                   legend(app.UIAxes,'Preform','Furnace');
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'T (°C)');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'Rc/Rcontact'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   plot(app.UIAxes,app.result.z,app.result.Rc./app.result.rcontact);
                   app.UIAxes.XLim = [0 app.param.L];
                   xlabel(app.UIAxes,'z (m)');
                   ylabel(app.UIAxes,'Rc/Rcontact');
                   legend(app.UIAxes,'hide');
                   daspect(app.UIAxes,'auto');
               end
               if (strcmp(value,'Schematic'))
                   app.zmSliderLabel.Visible = 'on';
                   app.zmSlider.Limits = [0 app.result.z(end)];
                   app.zmSlider.MajorTicks=linspace(0,app.result.z(end),11);
                   app.zmSlider.Visible = 'on';
                   z=app.zmSlider.Value;
                   cla(app.UIAxes);
                   [A,pos]=min(abs(z-app.result.z));
                   legend(app.UIAxes,'hide');
                   xlabel(app.UIAxes,'x (µm)');
                   ylabel(app.UIAxes,'y (µm)');
                   app.graph1.r0=rectangle(app.UIAxes,'Position',[-app.result.R(pos)*1E6,-app.result.R(pos)*1E6,2*app.result.R(pos)*1E6,2*app.result.R(pos)*1E6],'Curvature',[1,1], 'FaceColor','r');
                   app.graph1.r0bis=rectangle(app.UIAxes,'Position',[-app.result.r(pos)*1E6,-app.result.r(pos)*1E6,2*app.result.r(pos)*1E6,2*app.result.r(pos)*1E6],'Curvature',[1,1], 'FaceColor','w');
                   for j=1:app.param.n
                      xcenter=(app.result.r(pos)-app.result.Rc(pos))*cos((j-1)*2*pi/app.param.n)*1E6;
                      ycenter=(app.result.r(pos)-app.result.Rc(pos))*sin((j-1)*2*pi/app.param.n)*1E6;
                      app.graph1.r(j)=rectangle(app.UIAxes,'Position',[xcenter-app.result.Rc(pos)*1E6,ycenter-app.result.Rc(pos)*1E6,2*app.result.Rc(pos)*1E6,2*app.result.Rc(pos)*1E6],'Curvature',[1,1], 'FaceColor','r');
                      app.graph1.rbis(j)=rectangle(app.UIAxes,'Position',[xcenter-app.result.rc(pos)*1E6,ycenter-app.result.rc(pos)*1E6,2*app.result.rc(pos)*1E6,2*app.result.rc(pos)*1E6],'Curvature',[1,1], 'FaceColor','w');
                   end
                   app.UIAxes.XLim = app.UIAxes.YLim;
                  daspect(app.UIAxes,[1,1,1]);
               end
            else
                if (strcmp(value,'Temperature'))
                   app.zmSliderLabel.Visible = 'off';
                   app.zmSlider.Visible = 'off';
                   Lfour=app.FurnacelengthmEditField.Value;
                   L=app.LmEditField.Value;
                   Tmax=app.TmaxCEditField.Value;
                   app.UIAxes.XLim = [0 L];
                   if ~isempty(app.paramini.furnace_profile)
                          z=linspace(0,L,1E5);
                          plot(app.UIAxes,z,Tmax*arrayfun(app.paramini.furnace_profile,z).*(z<=Lfour)+app.ExternaltemperatureCEditField.Value*(z>Lfour));
                   else
                          z=linspace(Lfour,L,1E5);
                          plot(app.UIAxes,z,app.ExternaltemperatureCEditField.Value*(z>=Lfour));
                   end
                  legend(app.UIAxes,'Temperature profile');
                  xlabel(app.UIAxes,'z (m)');
                  ylabel(app.UIAxes,'T (°C)');
                  daspect(app.UIAxes,'auto');
                end
           end
        end

        
        function trace_optim(app)
            value = app.GrandeurreprsenterDropDown_2.Value;
            if (app.optim_fait)
               if (strcmp(value,'V'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.w*60);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'V (m/min)');
                   legend(app.UIAxes_2,strcat('Vf = ',num2str(app.optim.result.w(end)*60),' m/min'));
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'D'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.R*2*1E6);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'D (µm)');
                   legend(app.UIAxes_2,strcat('Df = ',num2str(app.optim.result.R(end)*2*1E6),' µm'));
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'d'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.r*2*1E6);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'d (µm)');
                   legend(app.UIAxes_2,strcat('df = ',num2str(app.optim.result.r(end)*2*1E6),' µm'));
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'Dc'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.Rc*2*1E6);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'Dc (µm)');
                   legend(app.UIAxes_2,strcat('Dcf = ',num2str(app.optim.result.Rc(end)*2*1E6),' µm'));
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'dc'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.rc*2*1E6);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'dc (µm)');
                   legend(app.UIAxes_2,strcat('dcf = ',num2str(app.optim.result.rc(end)*2*1E6),' µm'));
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'t'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.Rc*1E6-app.optim.result.rc*1E6);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'t (µm)');
                   legend(app.UIAxes_2,strcat('tf = ',num2str(app.optim.result.Rc(end)*1E6-app.optim.result.rc(end)*1E6),' µm'));
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'Dcore'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.r*2E6-app.optim.result.Rc*4E6);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'Dcore (µm)');
                   legend(app.UIAxes_2,strcat('Dcore_f = ',num2str(app.optim.result.r(end)*2E6-app.optim.result.Rc(end)*4E6),' µm'));
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'dc/Dc'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.rc./app.optim.result.Rc);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'dc/Dc');
                   legend(app.UIAxes_2,strcat('dcf/Dcf = ',num2str(app.optim.result.rc(end)/app.optim.result.Rc(end))));
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'Draw tension'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.tau/9.81*1000);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'Tension (g)');
                   legend(app.UIAxes_2,strcat('\tauf = ',num2str(app.optim.result.tau(end)/9.81*1000),' g'));
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'Temperature'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.T-273,app.optim.result.z,app.optim.result.Tmax*arrayfun(app.optim.param.profil,app.optim.result.z).*(app.optim.result.z<=app.optim.param.Lfour)+app.optim.param.Text*(app.optim.result.z>app.optim.param.Lfour));
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   legend(app.UIAxes_2,'Preform','Furnace');
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'T (°C)');
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'Rc/Rcontact'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                   plot(app.UIAxes_2,app.optim.result.z,app.optim.result.Rc./app.optim.result.rcontact);
                   app.UIAxes_2.XLim = [0 app.optim.param.L];
                   xlabel(app.UIAxes_2,'z (m)');
                   ylabel(app.UIAxes_2,'Rc/Rcontact');
                   legend(app.UIAxes_2,'hide');
                   daspect(app.UIAxes_2,'auto');
               end
               if (strcmp(value,'Schematic'))
                   app.zmSlider_2Label.Visible = 'on';
                   app.zmSlider_2.Limits = [0 app.optim.result.z(end)];
                   app.zmSlider_2.MajorTicks=linspace(0,app.optim.result.z(end),11);
                   app.zmSlider_2.Visible = 'on';
                   z=app.zmSlider_2.Value;
                   cla(app.UIAxes_2);
                   [A,pos]=min(abs(z-app.optim.result.z));
                   legend(app.UIAxes_2,'hide');
                   xlabel(app.UIAxes_2,'x (µm)');
                   ylabel(app.UIAxes_2,'y (µm)');
                   app.graph2.r0=rectangle(app.UIAxes_2,'Position',[-app.optim.result.R(pos)*1E6,-app.optim.result.R(pos)*1E6,2*app.optim.result.R(pos)*1E6,2*app.optim.result.R(pos)*1E6],'Curvature',[1,1], 'FaceColor','r');
                   app.graph2.r0bis=rectangle(app.UIAxes_2,'Position',[-app.optim.result.r(pos)*1E6,-app.optim.result.r(pos)*1E6,2*app.optim.result.r(pos)*1E6,2*app.optim.result.r(pos)*1E6],'Curvature',[1,1], 'FaceColor','w');
                   for j=1:app.optim.param.n
                      xcenter=(app.optim.result.r(pos)-app.optim.result.Rc(pos))*cos((j-1)*2*pi/app.optim.param.n)*1E6;
                      ycenter=(app.optim.result.r(pos)-app.optim.result.Rc(pos))*sin((j-1)*2*pi/app.optim.param.n)*1E6;
                      app.graph2.r(j)=rectangle(app.UIAxes_2,'Position',[xcenter-app.optim.result.Rc(pos)*1E6,ycenter-app.optim.result.Rc(pos)*1E6,2*app.optim.result.Rc(pos)*1E6,2*app.optim.result.Rc(pos)*1E6],'Curvature',[1,1], 'FaceColor','r');
                      app.graph2.rbis(j)=rectangle(app.UIAxes_2,'Position',[xcenter-app.optim.result.rc(pos)*1E6,ycenter-app.optim.result.rc(pos)*1E6,2*app.optim.result.rc(pos)*1E6,2*app.optim.result.rc(pos)*1E6],'Curvature',[1,1], 'FaceColor','w');
                   end
                   app.UIAxes_2.XLim = app.UIAxes_2.YLim;
                  daspect(app.UIAxes_2,[1,1,1])
               end
            else
                if (strcmp(value,'Temperature'))
                   app.zmSlider_2Label.Visible = 'off';
                   app.zmSlider_2.Visible = 'off';
                    Lfour=app.FurnacelengthmEditField_2.Value;
                    L=app.OptimLEditField.Value;
                    Tmax=(app.OptimTmaxmaxEditField.Value+app.OptimTmaxminEditField.Value)/2;
                    app.UIAxes_2.XLim = [0 L];
                   if ~isempty(app.paramini.furnace_profile_optim)
                          z=linspace(0,L,1E5);
                          plot(app.UIAxes_2,z,Tmax*arrayfun(app.paramini.furnace_profile_optim,z).*(z<=Lfour)+app.ExternaltemperatureCEditField.Value*(z>Lfour));
                   else
                          z=linspace(Lfour,L,1E5);
                          plot(app.UIAxes_2,z,app.ExternaltemperatureCEditField.Value*(z>=Lfour));
                   end
                          legend(app.UIAxes_2,'Temperature profile');
                          xlabel(app.UIAxes_2,'z (m)');
                          ylabel(app.UIAxes_2,'T (°C)');
                          daspect(app.UIAxes_2,'auto');
               end
            end
        end

        function trace_parameters(app)
            T=250:10:2500;
            parameter = app.GrandeurreprsenterDropDown_3.Value;
            if strcmp(parameter,"Volumic emissivity")
                if ~isempty(app.paramini.alpha)
                    plot(app.UIAxes_3,T,arrayfun(app.paramini.alpha,T));
                    ylabel(app.UIAxes_3,'Volumic emissivity (1/m)');
                    app.UIAxes_3.YScale = 'linear';
                else
                    cla(app.UIAxes_3)
                end
            end
            if strcmp(parameter,"Viscosity")
                if ~isempty(app.paramini.viscosity)
                    plot(app.UIAxes_3,T,arrayfun(app.paramini.viscosity,T));
                    ylabel(app.UIAxes_3,'Viscosity (N.s/m²)');
                    app.UIAxes_3.YScale = 'log';
                else
                    cla(app.UIAxes_3)
                end
            end
            if strcmp(parameter,"Specific heat")
                if ~isempty(app.paramini.Cp)
                    plot(app.UIAxes_3,T,arrayfun(app.paramini.Cp,T));
                    ylabel(app.UIAxes_3,'Specific heat (J/kg/K)');
                    app.UIAxes_3.YScale = 'linear';
                else
                    cla(app.UIAxes_3)
                end
            end
            if strcmp(parameter,"Volumic mass")
                if ~isempty(app.paramini.rho)
                    plot(app.UIAxes_3,T,arrayfun(app.paramini.rho,T));
                    ylabel(app.UIAxes_3,'Volumic mass (kg/m^3)');
                    app.UIAxes_3.YScale = 'linear';
                else
                    cla(app.UIAxes_3)
                end
            end
            if strcmp(parameter,"Surface tension")
                if ~isempty(app.paramini.gamma)
                    plot(app.UIAxes_3,T,arrayfun(app.paramini.gamma,T));
                    ylabel(app.UIAxes_3,'gamma (N/m)');
                    app.UIAxes_3.YScale = 'linear';
                else
                    cla(app.UIAxes_3)
                end
            end
                
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning off;
            app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure.Name = 'Tubular anti-resonant hollow-core fibers drawing simulation v1.3';
            parpool("threads");

            app.UIAxes_3.XLim = [250 2500];
            app.UIAxes_3.XTick = 250:250:2500;
            Volumicemissivity1mEditFieldValueChanged(app, []);
            ViscosityNsmEditFieldValueChanged(app, []);
            SurfacetensionNmEditFieldValueChanged(app,[]);
            Volumicmasskgm3EditFieldValueChanged(app,[]);
            SpecificheatJkgKEditFieldValueChanged(app,[]);
            trace_parameters(app);

            FurnacetemperatureprofileEditFieldValueChanged(app, true);
            FurnacetemperatureprofileEditField_2ValueChanged(app, true);

            app.calcul_fait=false;
            app.optim_fait=false;

            creationDateTime1=NaT;
            creationDateTime2=NaT;
            if isfile(strcat(userpath,'\param_simulation.mat'))
                load(strcat(userpath,'\param_simulation.mat'),'param_save');
                d1=System.IO.File.GetCreationTime(strcat(userpath,'\param_simulation.mat'));
                creationDateTime1 = datetime(d1.Year, d1.Month, d1.Day, d1.Hour, d1.Minute, d1.Second);
                app.PcoremBarEditField.Value=param_save.Pcore;
                app.FeedspeedmmminEditField.Value=param_save.w0;
                app.D0mmEditField.Value=param_save.D0;
                app.d0mmEditField.Value=param_save.d0;
                app.Dc0mmEditField.Value=param_save.Dc0;
                app.dc0mmEditField.Value=param_save.dc0;
                app.LmEditField.Value=param_save.L;
                app.DrawtensiongEditField.Value=param_save.tension;
                app.NumberofcapillariesEditField.Value=param_save.n;
                app.TmaxCEditField.Value=param_save.Tmax;
                app.DeltaPEditField.Value=param_save.Pcap; 
                app.FurnacelengthmEditField.Value=param_save.Lfour;
                app.FurnacetemperatureprofileEditField.Value=param_save.profil;
            end
            if isfile(strcat(userpath,'param_optimisation.mat'))
                load(strcat(userpath,'param_optimisation.mat'),'param_save');
                d2=System.IO.File.GetCreationTime(strcat(userpath,'\param_optimisation.mat'));
                creationDateTime2 = datetime(d2.Year, d2.Month, d2.Day, d2.Hour, d2.Minute, d2.Second);
                app.OptimViEditField.Value=param_save.w0;
                app.OptimTensionEditField.Value=param_save.tension;
                app.OptimD0EditField.Value=param_save.D0;
                app.Optimd0EditField.Value=param_save.d0;
                app.OptimDc0EditField.Value=param_save.Dc0;
                app.Optimdc0EditField.Value=param_save.dc0;
                app.OptimLEditField.Value=param_save.L;
                app.OptimNbEditField.Value=param_save.n;
                app.OptimtfEditField.Value=param_save.tf;
                app.OptimDcfEditField.Value=param_save.Dcf;
                app.OptimDcoreEditField.Value=param_save.Dcore;
                app.OptimTmaxminEditField.Value=param_save.Tmax_min;
                app.OptimTmaxmaxEditField.Value=param_save.Tmax_max;
                app.OptimPcoreminEditField.Value=param_save.Pcore_min;
                app.OptimPcoremaxEditField.Value=param_save.Pcore_max;
                app.OptimDeltaPminEditField.Value=param_save.Pcap_min;
                app.OptimDeltaPmaxEditField.Value=param_save.Pcap_max;   
                app.FurnacelengthmEditField_2.Value=param_save.Lfour;
                app.FurnacetemperatureprofileEditField_2.Value=param_save.profil;
            end
            file_with_param=0;
            if isnat(creationDateTime1)
                if ~isnat(creationDateTime2)
                    file_with_param=2;
                end
            else
                if (isnat(creationDateTime2))
                    file_with_param=1;
                else
                    if creationDateTime1>creationDateTime2
                        file_with_param=1;
                    else
                        file_with_param=2;
                    end
                end
            end
            if file_with_param~=0
                if file_with_param==1
                    load(strcat(userpath,'\param_simulation.mat'),'param_save');
                end
                app.GravitationalaccelerationmsEditField.Value=param_save.g;
                app.EditField.Value=param_save.dT0;
                app.NewtonheattransfercoefficientWmKEditField.Value=param_save.N;
                app.StefanBoltzmannconstantWmK4EditField.Value=param_save.sigma;
                app.Volumicmasskgm3EditField.Value=param_save.rho;
                app.SurfacetensionNmEditField.Value=param_save.gamma;
                app.SpecificheatJkgKEditField.Value=param_save.Cp;
                app.ViscosityNsmEditField.Value=param_save.viscosity;
                app.Volumicemissivity1mEditField.Value=param_save.alpha;
                app.ExternaltemperatureCEditField.Value=param_save.Text;
            end


            dc0 = app.Optimdc0EditField.Value;
            Dc0 = app.OptimDc0EditField.Value;
            app.dc0Dc0Label.Text=strcat('dc0/Dc0 = ',num2str(dc0/Dc0));
            dc0 = app.dc0mmEditField.Value;
            Dc0 = app.Dc0mmEditField.Value;
            app.dc0Dc0Label_2.Text=strcat('dc0/Dc0 = ',num2str(dc0/Dc0));
            update_optim_objectifs(app);
            app.GrandeurreprsenterDropDown.Value='Temperature';
            app.GrandeurreprsenterDropDown_2.Value='Temperature';
            trace(app);
            trace_optim(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if (app.D0mmEditField.Value<=app.d0mmEditField.Value)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'D0<=d0','Input Error');
                return;
            end
            if (app.Dc0mmEditField.Value<=app.dc0mmEditField.Value)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Dc0<=dc0','Input Error');
                return;
            end
            n=app.NumberofcapillariesEditField.Value;
            rcontact=app.d0mmEditField.Value/2*sin(pi/n)/(1+sin(pi/n));
            if (app.Dc0mmEditField.Value/2>rcontact)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Rc0>Rcontact','Input Error');
                return;
            end
                       
            if ~isempty(app.paramini.rho)
                app.param.rho=app.paramini.rho;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The volumic mass is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.gamma)
                app.param.gamma=app.paramini.gamma;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The surface tension is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.Cp)
                app.param.Cp=app.paramini.Cp;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The specific heat is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.alpha)
                app.param.alpha=app.paramini.alpha;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The volumic emissivity is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.viscosity)
                app.param.viscosity=app.paramini.viscosity;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The viscosity is not defined','Input Error');
                return;
            end  
            if ~isempty(app.paramini.furnace_profile)
                app.param.profil=app.paramini.furnace_profile;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The furnace profile is not defined','Input Error');
                return;
            end  
            app.param.sigma=app.StefanBoltzmannconstantWmK4EditField.Value;
            app.param.N=app.NewtonheattransfercoefficientWmKEditField.Value;
            app.param.g=app.GravitationalaccelerationmsEditField.Value;
            app.param.dT0=app.EditField.Value;
            app.param.Text=app.ExternaltemperatureCEditField.Value;

            d=uiprogressdlg(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Title','Computing solution...', 'Indeterminate','on');

            app.param.pcore=app.PcoremBarEditField.Value*100;
            app.param.r0=app.d0mmEditField.Value/2*1E-3;
            app.param.R0=app.D0mmEditField.Value/2*1E-3;
            app.param.w0=app.FeedspeedmmminEditField.Value*1E-3/60;
            app.param.n=app.NumberofcapillariesEditField.Value;
            app.param.Rc0=app.Dc0mmEditField.Value/2*1E-3;
            app.param.rc0=app.dc0mmEditField.Value/2*1E-3;
            app.param.tension=app.DrawtensiongEditField.Value/1000*9.81;
            app.param.L=app.LmEditField.Value;
            app.param.Tmax=app.TmaxCEditField.Value;
            app.param.Pcap=app.DeltaPEditField.Value*100;
            app.param.DeltaP=app.param.Pcap-app.param.pcore;
            app.param.Lfour=app.FurnacelengthmEditField.Value;

            param_save.Pcore=app.PcoremBarEditField.Value;
            param_save.w0=app.FeedspeedmmminEditField.Value;
            param_save.D0=app.D0mmEditField.Value;
            param_save.d0=app.d0mmEditField.Value;
            param_save.Dc0=app.Dc0mmEditField.Value;
            param_save.dc0=app.dc0mmEditField.Value;
            param_save.L=app.LmEditField.Value;
            param_save.tension=app.DrawtensiongEditField.Value;
            param_save.n=app.NumberofcapillariesEditField.Value;
            param_save.Tmax=app.TmaxCEditField.Value;
            param_save.Pcap=app.DeltaPEditField.Value;
            param_save.Lfour=app.FurnacelengthmEditField.Value;
            param_save.profil=app.FurnacetemperatureprofileEditField.Value;

            param_save.g=app.GravitationalaccelerationmsEditField.Value;
            param_save.dT0=app.EditField.Value;
            param_save.N=app.NewtonheattransfercoefficientWmKEditField.Value;
            param_save.sigma=app.StefanBoltzmannconstantWmK4EditField.Value;
            param_save.rho=app.Volumicmasskgm3EditField.Value;
            param_save.gamma=app.SurfacetensionNmEditField.Value;
            param_save.Cp=app.SpecificheatJkgKEditField.Value;
            param_save.viscosity=app.ViscosityNsmEditField.Value;
            param_save.alpha=app.Volumicemissivity1mEditField.Value;
            param_save.Text=app.ExternaltemperatureCEditField.Value;

            save(strcat(userpath,'\param_simulation.mat'),'param_save')
            T0=app.param.Tmax*app.param.profil(0)+273-app.param.dT0;
            fct=@(t,y) RK_perso7(app.param,t,y,app.param.Tmax,app.param.pcore,app.param.DeltaP);
            x=optim_tension(app.param,app.param.Tmax,app.param.pcore,1E-10);
            options_ode = odeset('Reltol',1e-10);
            app.result.sol1=ode45(fct,[0 app.param.Lfour],[x,app.param.w0,app.param.R0^2*app.param.w0,app.param.r0^2*app.param.w0,T0,app.param.Rc0^2*app.param.w0,app.param.rc0^2*app.param.w0],options_ode);
            fct=@(t,y) RK_perso7_293K(app.param,t,y,app.param.pcore,app.param.DeltaP);
            app.result.sol2=ode45(fct,[app.param.Lfour app.param.L],app.result.sol1.y(:,end),options_ode);
            nb1=ceil(max(app.result.sol1.x)/1E-5)+1;
            z1=linspace(0,max(app.result.sol1.x),nb1);
            y1=deval(app.result.sol1,z1);
            nb2=ceil((max(app.result.sol2.x)-app.param.Lfour)/1E-5)+1;
            z2=linspace(app.param.Lfour,max(app.result.sol2.x),nb2);
            y2=deval(app.result.sol2,z2);
            app.result.z=[z1,z2(2:end)];
            app.result.tau=[y1(1,:),y2(1,2:end)];
            app.result.w=[y1(2,:),y2(2,2:end)];
            app.result.R2w=[y1(3,:),y2(3,2:end)];
            app.result.r2w=[y1(4,:),y2(4,2:end)];
            app.result.T=[y1(5,:),y2(5,2:end)];
            app.result.Rc2w=[y1(6,:),y2(6,2:end)];
            app.result.rc2w=[y1(7,:),y2(7,2:end)];
            app.result.R=sqrt(app.result.R2w./app.result.w);
            app.result.r=sqrt(app.result.r2w./app.result.w);
            app.result.mu=arrayfun(app.param.viscosity,app.result.T);
            app.result.dwdz=app.result.tau./(3*pi*app.result.mu.*(app.result.R.^2-app.result.r.^2));
            app.result.Rc=sqrt(app.result.Rc2w./app.result.w);
            app.result.rc=sqrt(app.result.rc2w./app.result.w);
            app.result.rcontact=app.result.r*sin(pi/app.param.n)/(1+sin(pi/app.param.n));
            app.calcul_fait=true;
            app.SaveresultsButton.Enable = 'on';
            close(d);
            app.dfLabel.Text=strcat('df = ',num2str(app.result.r(end)*2*1E6),' µm');
            app.DfLabel.Text=strcat('Df = ',num2str(app.result.R(end)*2*1E6),' µm');
            app.dcfLabel.Text=strcat('dcf = ',num2str(app.result.rc(end)*2*1E6),' µm');
            app.DcfLabel.Text=strcat('Dcf = ',num2str(app.result.Rc(end)*2*1E6),' µm');
            app.DcoreLabel.Text=strcat('Dcore = ',num2str((app.result.r(end)-2*app.result.Rc(end))*2*1E6),' µm');
            app.tfLabel.Text=strcat('tf = ',num2str((app.result.Rc(end)-app.result.rc(end))*1E6),' µm');
            app.VfLabel.Text=strcat('Vf = ',num2str(app.result.w(end)*60),' m/min');
            app.dcfDcfLabel_2.Text=strcat('dcf/Dcf = ',num2str(app.result.rc(end)/app.result.Rc(end)));
            app.maxRcRcontactLabel.Interpreter='html';
            if (max(app.result.Rc./app.result.rcontact)>=1)
                app.maxRcRcontactLabel.Text=strcat('max(Rc/Rcontact) = <font color="red">',num2str(max(app.result.Rc./app.result.rcontact)),'</font>');
            else
                app.maxRcRcontactLabel.Text=strcat('max(Rc/Rcontact) = <font color="green">',num2str(max(app.result.Rc./app.result.rcontact)),'</font>');
            end
            trace(app);
        end

        % Value changed function: GrandeurreprsenterDropDown
        function GrandeurreprsenterDropDownValueChanged(app, event)
            %value = app.GrandeurreprsenterDropDown.Value;
            trace(app);
        end

        % Value changing function: zmSlider
        function zmSliderValueChanging(app, event)
            changingValue = event.Value;
            [A,pos]=min(abs(changingValue-app.result.z));
            app.graph1.r0.Position=[-app.result.R(pos)*1E6,-app.result.R(pos)*1E6,2*app.result.R(pos)*1E6,2*app.result.R(pos)*1E6];
            app.graph1.r0bis.Position=[-app.result.r(pos)*1E6,-app.result.r(pos)*1E6,2*app.result.r(pos)*1E6,2*app.result.r(pos)*1E6];
            for j=1:app.param.n
                xcenter=(app.result.r(pos)-app.result.Rc(pos))*cos((j-1)*2*pi/app.param.n)*1E6;
                ycenter=(app.result.r(pos)-app.result.Rc(pos))*sin((j-1)*2*pi/app.param.n)*1E6;
                app.graph1.r(j).Position=[xcenter-app.result.Rc(pos)*1E6,ycenter-app.result.Rc(pos)*1E6,2*app.result.Rc(pos)*1E6,2*app.result.Rc(pos)*1E6];
                app.graph1.rbis(j).Position=[xcenter-app.result.rc(pos)*1E6,ycenter-app.result.rc(pos)*1E6,2*app.result.rc(pos)*1E6,2*app.result.rc(pos)*1E6];
            end
            app.UIAxes.XLim = app.UIAxes.YLim;
        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
            filter = {'*.mat'};
            [file, path] = uiputfile(filter);
            result=app.result;
            param=app.param;
            save(strcat(path,file),'result','param');
        end

        % Value changed function: OptimViEditField
        function OptimViEditFieldValueChanged(app, event)
            value = app.OptimViEditField.Value;
            update_optim_objectifs(app);
        end

        % Value changed function: OptimD0EditField
        function OptimD0EditFieldValueChanged(app, event)
            value = app.OptimD0EditField.Value;
            update_optim_objectifs(app);
        end

        % Value changed function: Optimd0EditField
        function Optimd0EditFieldValueChanged(app, event)
            value = app.Optimd0EditField.Value;
            update_optim_objectifs(app);
        end

        % Value changed function: OptimDc0EditField
        function OptimDc0EditFieldValueChanged(app, event)
            dc0 = app.Optimdc0EditField.Value;
            Dc0 = app.OptimDc0EditField.Value;
            app.dc0Dc0Label.Text=strcat('dc0/Dc0 = ',num2str(dc0/Dc0));
            update_optim_objectifs(app);
        end

        % Value changed function: Optimdc0EditField
        function Optimdc0EditFieldValueChanged(app, event)
            dc0 = app.Optimdc0EditField.Value;
            Dc0 = app.OptimDc0EditField.Value;
            app.dc0Dc0Label.Text=strcat('dc0/Dc0 = ',num2str(dc0/Dc0));
            update_optim_objectifs(app);
        end

        % Value changed function: OptimtfEditField
        function OptimtfEditFieldValueChanged(app, event)
            value = app.OptimtfEditField.Value;
            update_optim_objectifs(app);
        end

        % Value changed function: OptimDcfEditField
        function OptimDcfEditFieldValueChanged(app, event)
            value = app.OptimDcfEditField.Value;
            update_optim_objectifs(app);
        end

        % Value changed function: OptimDcoreEditField
        function OptimDcoreEditFieldValueChanged(app, event)
            value = app.OptimDcoreEditField.Value;
            update_optim_objectifs(app);
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            if (app.OptimD0EditField.Value<=app.Optimd0EditField.Value)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'D0<=d0','Input Error');
                return;
            end
            if (app.OptimDc0EditField.Value<=app.Optimdc0EditField.Value)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Dc0<=dc0','Input Error');
                return;
            end
            n=app.OptimNbEditField.Value;
            rcontact=app.Optimd0EditField.Value/2*sin(pi/n)/(1+sin(pi/n));
            if (app.OptimDc0EditField.Value/2>rcontact)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Rc0>Rcontact','Input Error');
                return;
            end
            if(app.OptimDeltaPmaxEditField.Value<=app.OptimDeltaPminEditField.Value)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Pcap_max<=Pcap_min','Input Error');
                return;
            end
            if(app.OptimTmaxmaxEditField.Value<=app.OptimTmaxminEditField.Value)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Tmax_max<=Tmax_min','Input Error');
                return;
            end
            if(app.OptimPcoremaxEditField.Value<=app.OptimPcoreminEditField.Value)
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Pcore_max<=Pcore_min','Input Error');
                return;
            end
            
            if ~isempty(app.paramini.rho)
                app.optim.param.rho=app.paramini.rho;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The volumic mass is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.gamma)
                app.optim.param.gamma=app.paramini.gamma;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The surface tension is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.Cp)
                app.optim.param.Cp=app.paramini.Cp;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The specific heat is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.alpha)
                app.optim.param.alpha=app.paramini.alpha;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The volumic emissivity is not defined','Input Error');
                return;
            end
            if ~isempty(app.paramini.viscosity)
                app.optim.param.viscosity=app.paramini.viscosity;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The viscosity is not defined','Input Error');
                return;
            end  
            if ~isempty(app.paramini.furnace_profile_optim)
                app.optim.param.profil=app.paramini.furnace_profile_optim;
            else
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'The furnace profile is not defined','Input Error');
                return;
            end  
            app.optim.param.sigma=app.StefanBoltzmannconstantWmK4EditField.Value;
            app.optim.param.N=app.NewtonheattransfercoefficientWmKEditField.Value;
            app.optim.param.g=app.GravitationalaccelerationmsEditField.Value;
            app.optim.param.dT0=app.EditField.Value;
            app.optim.param.Text=app.ExternaltemperatureCEditField.Value;
            
            
            d=uiprogressdlg(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Title','Computing solution', 'Indeterminate','on');
            d.Message='Initialization';
            param_save.w0=app.OptimViEditField.Value;
            param_save.tension=app.OptimTensionEditField.Value;
            param_save.D0=app.OptimD0EditField.Value;
            param_save.d0=app.Optimd0EditField.Value;
            param_save.Dc0=app.OptimDc0EditField.Value;
            param_save.dc0=app.Optimdc0EditField.Value;
            param_save.L=app.OptimLEditField.Value;
            param_save.n=app.OptimNbEditField.Value;
            param_save.tf=app.OptimtfEditField.Value;
            param_save.Dcf=app.OptimDcfEditField.Value;
            param_save.Dcore=app.OptimDcoreEditField.Value;
            param_save.Tmax_min=app.OptimTmaxminEditField.Value;
            param_save.Tmax_max=app.OptimTmaxmaxEditField.Value;
            param_save.Pcore_min=app.OptimPcoreminEditField.Value;
            param_save.Pcore_max=app.OptimPcoremaxEditField.Value;
            param_save.Pcap_min=app.OptimDeltaPminEditField.Value;
            param_save.Pcap_max=app.OptimDeltaPmaxEditField.Value;
            param_save.Lfour=app.FurnacelengthmEditField_2.Value;
            param_save.profil=app.FurnacetemperatureprofileEditField_2.Value;

            param_save.g=app.GravitationalaccelerationmsEditField.Value;
            param_save.dT0=app.EditField.Value;
            param_save.N=app.NewtonheattransfercoefficientWmKEditField.Value;
            param_save.sigma=app.StefanBoltzmannconstantWmK4EditField.Value;
            param_save.rho=app.Volumicmasskgm3EditField.Value;
            param_save.gamma=app.SurfacetensionNmEditField.Value;
            param_save.Cp=app.SpecificheatJkgKEditField.Value;
            param_save.viscosity=app.ViscosityNsmEditField.Value;
            param_save.alpha=app.Volumicemissivity1mEditField.Value;
            param_save.Text=app.ExternaltemperatureCEditField.Value;

            save(strcat(userpath,'\param_optimisation.mat'),'param_save')
            
            app.optim.param.r0=app.Optimd0EditField.Value/2*1E-3;
            app.optim.param.R0=app.OptimD0EditField.Value/2*1E-3;
            app.optim.param.w0=app.OptimViEditField.Value*1E-3/60;
            app.optim.param.n=app.OptimNbEditField.Value;
            app.optim.param.Rc0=app.OptimDc0EditField.Value/2*1E-3;
            app.optim.param.rc0=app.Optimdc0EditField.Value/2*1E-3;
            app.optim.param.tension=app.OptimTensionEditField.Value/1000*9.81;
            app.optim.param.L=app.OptimLEditField.Value;
            app.optim.param.tf=app.OptimtfEditField.Value*1E-6;
            app.optim.param.Rcf=app.OptimDcfEditField.Value*1E-6/2;
            app.optim.param.Dcore=app.OptimDcoreEditField.Value*1E-6;
            app.optim.param.rcf=app.optim.param.Rcf-app.optim.param.tf;
            app.optim.param.rf=app.optim.param.Dcore/2+2*app.optim.param.Rcf;
            app.optim.param.wf=(app.optim.param.Rc0^2-app.optim.param.rc0^2)/(app.optim.param.Rcf^2-app.optim.param.rcf^2)*app.optim.param.w0;
            app.optim.param.Rf=sqrt((app.optim.param.R0^2-app.optim.param.r0^2)*app.optim.param.w0/app.optim.param.wf+app.optim.param.rf^2);
            app.optim.param.Lfour=app.FurnacelengthmEditField_2.Value;
            Pmin=app.OptimPcoreminEditField.Value*100;
            Pmax=app.OptimPcoremaxEditField.Value*100;
            Tmin=app.OptimTmaxminEditField.Value;
            Tmax=app.OptimTmaxmaxEditField.Value;
            %Tmax a de l'influence sur R mais peu sur R/r
            %Pcore a de l'influence sur R/r mais peu sur R
            %=>en première approx on peut faire un encadrement des deux grandeurs
            %=>puis optimisation sous contrainte
            %Optimisation à 10°C près
            if (Tmax-Tmin>20)
                d.Message='Approximation of Tmax'; 
                Tmin2=zeros(1,2);
                Tmax2=zeros(1,2);
                P=[Pmin,Pmax];
                param2=[app.optim.param,app.optim.param];
                parfor boucle=1:2
                    warning off;
                     [Tmin2(boucle),Tmax2(boucle)]=optimisation_T(param2(boucle),linspace(Tmin,Tmax,ceil((Tmax-Tmin)/10)+1),P(boucle));
                end
                Tmin=min(Tmin2);
                Tmax=max(Tmax2);
            end
            %Optimsisation à 1000 bars près
            if (Pmax-Pmin>2000)
                d.Message=strcat(num2str(Tmin),'<Tmax<',num2str(Tmax),'°C ; Approximation of Pcore 1/2');
                [Pmin1,Pmax1]=optimisation_P(app.optim.param,Tmin,linspace(Pmin,Pmax,ceil((Pmax-Pmin)/1000)+1));
                d.Message=strcat(num2str(Tmin),'<Tmax<',num2str(Tmax),'°C ; Approximation of Pcore 2/2');
                [Pmin2,Pmax2]=optimisation_P(app.optim.param,Tmax,linspace(Pmin,Pmax,ceil((Pmax-Pmin)/1000)+1));
                Pmin=min(Pmin1,Pmin2);
                Pmax=max(Pmax1,Pmax2);
            end
            d.Message=strcat('Optimization of the pair (Tmax,Pcore)');
            x0=[(Tmax+Tmin)/2,(Pmax+Pmin)/2];
            lb=[Tmin,Pmin];
            ub=[Tmax,Pmax];
            parambis=app.optim.param;
            func_multi=@(x) optimisation_TP(parambis,x);
            %Use parallel not working with global variable => parambis
            options=optimoptions('fmincon','Algorithm','interior-point','Display','none','StepTolerance',1E-10,'ObjectiveLimit',1E-5,'OptimalityTolerance',1E-5,'UseParallel',true);
            x=fmincon(func_multi,x0,[],[],[],[],lb,ub,[],options);
            app.OptimTmaxLabel.Text=strcat('Tmax = ',num2str(x(1)),'°C');
            app.OptimPcoreLabel.Text=strcat('Pcore = ',num2str(x(2)/100),' mbar');
            app.optim.result.Tmax=x(1);
            app.optim.result.Pcore=x(2);
            d.Message=strcat('Optimization of Pcap');
            DeltaPmin=app.OptimDeltaPminEditField.Value*100-app.optim.result.Pcore;
            DeltaPmax=app.OptimDeltaPmaxEditField.Value*100-app.optim.result.Pcore;
            func=@(t) optim_DeltaP(app.optim.param,app.optim.result.Tmax,app.optim.result.Pcore,t);
            lb=DeltaPmin;
            ub=DeltaPmax;
            options=optimset('Display','none','TolX',1E-1);
            dp=fminbnd(func,lb,ub,options);
            app.optim.result.DeltaP=dp;
            app.optim.result.Pcap=dp+app.optim.result.Pcore;
            app.OptimdpLabel.Text=strcat('Pcap = ',num2str(app.optim.result.Pcap/100,'%.2f'),' mbar');
            d.Message=strcat('Final computation');
            T0=app.optim.result.Tmax*app.optim.param.profil(0)+273-app.optim.param.dT0;
            fct=@(t,y) RK_perso7(app.optim.param,t,y,app.optim.result.Tmax,app.optim.result.Pcore,app.optim.result.DeltaP);
            x=optim_tension(app.optim.param,app.optim.result.Tmax,app.optim.result.Pcore,1E-10);
            options_ode = odeset('Reltol',1e-10,'AbsTol',1E-15);
            app.optim.result.sol1=ode45(fct,[0 app.optim.param.Lfour],[x,app.optim.param.w0,app.optim.param.R0^2*app.optim.param.w0,app.optim.param.r0^2*app.optim.param.w0,T0,app.optim.param.Rc0^2*app.optim.param.w0,app.optim.param.rc0^2*app.optim.param.w0],options_ode);
            fct=@(t,y) RK_perso7_293K(app.optim.param,t,y,app.optim.result.Pcore,app.optim.result.DeltaP);
            app.optim.result.sol2=ode45(fct,[app.optim.param.Lfour app.optim.param.L],app.optim.result.sol1.y(:,end),options_ode);
            nb1=ceil(app.optim.param.Lfour/1E-5)+1;
            z1=linspace(0,app.optim.param.Lfour,nb1);
            y1=deval(app.optim.result.sol1,z1);
            nb2=ceil((app.optim.param.L-app.optim.param.Lfour)/1E-5)+1;
            z2=linspace(app.optim.param.Lfour,app.optim.param.L,nb2);
            y2=deval(app.optim.result.sol2,z2);
            app.optim.result.z=[z1,z2(2:end)];
            app.optim.result.tau=[y1(1,:),y2(1,2:end)];
            app.optim.result.w=[y1(2,:),y2(2,2:end)];
            app.optim.result.R2w=[y1(3,:),y2(3,2:end)];
            app.optim.result.r2w=[y1(4,:),y2(4,2:end)];
            app.optim.result.T=[y1(5,:),y2(5,2:end)];
            app.optim.result.Rc2w=[y1(6,:),y2(6,2:end)];
            app.optim.result.rc2w=[y1(7,:),y2(7,2:end)];
            app.optim.result.R=sqrt(app.optim.result.R2w./app.optim.result.w);
            app.optim.result.r=sqrt(app.optim.result.r2w./app.optim.result.w);
            app.optim.result.mu=arrayfun(app.optim.param.viscosity,app.optim.result.T);
            app.optim.result.dwdz=app.optim.result.tau./(3*pi*app.optim.result.mu.*(app.optim.result.R.^2-app.optim.result.r.^2));
            app.optim.result.Rc=sqrt(app.optim.result.Rc2w./app.optim.result.w);
            app.optim.result.rc=sqrt(app.optim.result.rc2w./app.optim.result.w);
            app.optim.result.rcontact=app.optim.result.r*sin(pi/app.optim.param.n)/(1+sin(pi/app.optim.param.n));
            if ((abs(app.optim.result.Rc(end)-app.optim.param.Rcf)/app.optim.param.Rcf>0.01) | (abs(app.optim.result.Rc(end)-app.optim.result.rc(end)-app.optim.param.tf)/app.optim.param.tf>0.01) | (abs(app.optim.result.r(end)*2-app.optim.result.Rc(end)*4-app.optim.param.Dcore)/app.optim.param.Dcore>0.01))
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Final paremeters are different from the desired parameters. Check the intervals of the approximate parameters.','Error');
            end
            app.optim_fait=true;
            app.SaveresultsButton_2.Enable='on';
            close(d);
            app.OptimmaxLabel.Interpreter='html';
            if (max(app.optim.result.Rc./app.optim.result.rcontact)>=1)
                app.OptimmaxLabel.Text=strcat('max(Rc/Rcontact) = <font color="red">',num2str(max(app.optim.result.Rc./app.optim.result.rcontact)),'</font>');
            else
                app.OptimmaxLabel.Text=strcat('max(Rc/Rcontact) = <font color="green">',num2str(max(app.optim.result.Rc./app.optim.result.rcontact)),'</font>');
            end
            trace_optim(app);

        end

        % Button pushed function: SaveresultsButton_2
        function SaveresultsButton_2Pushed(app, event)
            filter = {'*.mat'};
            [file, path] = uiputfile(filter);
            result=app.optim.result;
            param=app.optim.param;
            save(strcat(path,file),'result','param');
        end

        % Value changed function: GrandeurreprsenterDropDown_2
        function GrandeurreprsenterDropDown_2ValueChanged(app, event)
            value = app.GrandeurreprsenterDropDown_2.Value;
            trace_optim(app);
        end

        % Value changing function: zmSlider_2
        function zmSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            [A,pos]=min(abs(changingValue-app.optim.result.z));
            app.graph2.r0.Position=[-app.optim.result.R(pos)*1E6,-app.optim.result.R(pos)*1E6,2*app.optim.result.R(pos)*1E6,2*app.optim.result.R(pos)*1E6];
            app.graph2.r0bis.Position=[-app.optim.result.r(pos)*1E6,-app.optim.result.r(pos)*1E6,2*app.optim.result.r(pos)*1E6,2*app.optim.result.r(pos)*1E6];
            for j=1:app.optim.param.n
                xcenter=(app.optim.result.r(pos)-app.optim.result.Rc(pos))*cos((j-1)*2*pi/app.optim.param.n)*1E6;
                ycenter=(app.optim.result.r(pos)-app.optim.result.Rc(pos))*sin((j-1)*2*pi/app.optim.param.n)*1E6;
                app.graph2.r(j).Position=[xcenter-app.optim.result.Rc(pos)*1E6,ycenter-app.optim.result.Rc(pos)*1E6,2*app.optim.result.Rc(pos)*1E6,2*app.optim.result.Rc(pos)*1E6];
                app.graph2.rbis(j).Position=[xcenter-app.optim.result.rc(pos)*1E6,ycenter-app.optim.result.rc(pos)*1E6,2*app.optim.result.rc(pos)*1E6,2*app.optim.result.rc(pos)*1E6];
            end
            app.UIAxes_2.XLim = app.UIAxes_2.YLim;
        end

        % Value changed function: Dc0mmEditField
        function Dc0mmEditFieldValueChanged(app, event)
            Dc0 = app.Dc0mmEditField.Value;
            dc0 = app.dc0mmEditField.Value;
            app.dc0Dc0Label_2.Text=strcat('dc0/Dc0 = ',num2str(dc0/Dc0));
        end

        % Value changed function: dc0mmEditField
        function dc0mmEditFieldValueChanged(app, event)
            dc0 = app.dc0mmEditField.Value;
            Dc0 = app.Dc0mmEditField.Value;
            app.dc0Dc0Label_2.Text=strcat('dc0/Dc0 = ',num2str(dc0/Dc0));
        end

        % Value changed function: LmEditField
        function LmEditFieldValueChanged(app, event)
            value = app.LmEditField.Value;
            trace(app);
        end

        % Value changed function: TmaxCEditField
        function TmaxCEditFieldValueChanged(app, event)
            value = app.TmaxCEditField.Value;
            trace(app);
        end

        % Value changed function: FurnacelengthmEditField
        function FurnacelengthmEditFieldValueChanged(app, event)
            value = app.FurnacelengthmEditField.Value;
            trace(app);
        end

        % Value changed function: FurnacetemperatureprofileEditField
        function FurnacetemperatureprofileEditFieldValueChanged(app, event)
            value = app.FurnacetemperatureprofileEditField.Value;
           try
                fun=str2func(strcat('@(z)',value));
                ztest=[0,app.FurnacelengthmEditField.Value];
                arrayfun(fun,ztest);
                app.FurnacetemperatureprofileEditField.FontColor=[0,0,0];
                app.paramini.furnace_profile=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in the furnace temperature profile','Input Error');
                app.FurnacetemperatureprofileEditField.FontColor=[1,0,0];
                app.paramini.furnace_profile=[];
            end
            trace(app);
        end

        % Value changed function: FurnacelengthmEditField_2
        function FurnacelengthmEditField_2ValueChanged(app, event)
            value = app.FurnacelengthmEditField_2.Value;
            trace_optim(app);
        end

        % Value changed function: FurnacetemperatureprofileEditField_2
        function FurnacetemperatureprofileEditField_2ValueChanged(app, event)
            value = app.FurnacetemperatureprofileEditField_2.Value;
           try
                fun=str2func(strcat('@(z)',value));
                ztest=[0,app.FurnacelengthmEditField_2.Value];
                arrayfun(fun,ztest);
                app.FurnacetemperatureprofileEditField_2.FontColor=[0,0,0];
                app.paramini.furnace_profile_optim=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in the furnace temperature profile','Input Error');
                app.FurnacetemperatureprofileEditField_2.FontColor=[1,0,0];
                app.paramini.furnace_profile_optim=[];
            end
            trace_optim(app);
        end

        % Value changed function: OptimLEditField
        function OptimLEditFieldValueChanged(app, event)
            value = app.OptimLEditField.Value;
            trace_optim(app);
        end

        % Value changed function: OptimTmaxminEditField
        function OptimTmaxminEditFieldValueChanged(app, event)
            value = app.OptimTmaxminEditField.Value;
            trace_optim(app);
        end

        % Value changed function: OptimTmaxmaxEditField
        function OptimTmaxmaxEditFieldValueChanged(app, event)
            value = app.OptimTmaxmaxEditField.Value;
            trace_optim(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            web('https://github.com/ovanvincq/HARF-drawing','-browser');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            web('https://github.com/ovanvincq/HARF-drawing','-browser');
        end

        % Close request function: 
        % TubularantiresonanthollowcorefibersdrawingsimulationUIFigure
        function TubularantiresonanthollowcorefibersdrawingsimulationUIFigureCloseRequest(app, event)
            delete(gcp)
            delete(app)
        end

        % Value changed function: Volumicmasskgm3EditField
        function Volumicmasskgm3EditFieldValueChanged(app, event)
            value = app.Volumicmasskgm3EditField.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.Volumicmasskgm3EditField.FontColor=[0,0,0];
                app.paramini.rho=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in volumic mass','Input Error');
                app.Volumicmasskgm3EditField.FontColor=[1,0,0];
                app.paramini.rho=[];
            end
            if ~isempty(event)
                trace_parameters(app);
            end
        end

        % Value changed function: SurfacetensionNmEditField
        function SurfacetensionNmEditFieldValueChanged(app, event)
            value = app.SurfacetensionNmEditField.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.SurfacetensionNmEditField.FontColor=[0,0,0];
                app.paramini.gamma=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in surface tension','Input Error');
                app.SurfacetensionNmEditField.FontColor=[1,0,0];
                app.paramini.gamma=[];
            end
             if ~isempty(event)
                trace_parameters(app);
            end
        end

        % Value changed function: SpecificheatJkgKEditField
        function SpecificheatJkgKEditFieldValueChanged(app, event)
            value = app.SpecificheatJkgKEditField.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.SpecificheatJkgKEditField.FontColor=[0,0,0];
                app.paramini.Cp=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in specific heat','Input Error');
                app.SpecificheatJkgKEditField.FontColor=[1,0,0];
                app.paramini.Cp=[];
            end
             if ~isempty(event)
                trace_parameters(app);
            end
        end

        % Value changed function: ViscosityNsmEditField
        function ViscosityNsmEditFieldValueChanged(app, event)
            value = app.ViscosityNsmEditField.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.ViscosityNsmEditField.FontColor=[0,0,0];
                app.paramini.viscosity=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in viscosity','Input Error');
                app.ViscosityNsmEditField.FontColor=[1,0,0];
                app.paramini.viscosity=[];
            end
             if ~isempty(event)
                trace_parameters(app);
            end
        end

        % Value changed function: Volumicemissivity1mEditField
        function Volumicemissivity1mEditFieldValueChanged(app, event)
            value = app.Volumicemissivity1mEditField.Value;
            try
                fun=str2func(strcat('@(T)',value));
                Ttest=[273,2500];
                arrayfun(fun,Ttest);
                app.Volumicemissivity1mEditField.FontColor=[0,0,0];
                app.paramini.alpha=fun;
            catch exception
                uialert(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure,'Syntax error in volumic emissivity','Input Error');
                app.Volumicemissivity1mEditField.FontColor=[1,0,0];
                app.paramini.alpha=[];
            end
            if ~isempty(event)
                trace_parameters(app);
            end
        end

        % Value changed function: GrandeurreprsenterDropDown_3
        function GrandeurreprsenterDropDown_3ValueChanged(app, event)
            value = app.GrandeurreprsenterDropDown_3.Value;
            trace_parameters(app);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            web('https://github.com/ovanvincq/HARF-drawing','-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure = uifigure('Visible', 'off');
            app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure.Name = 'Tubular anti-resonant hollow-core fibers drawing simulation';
            app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure.CloseRequestFcn = createCallbackFcn(app, @TubularantiresonanthollowcorefibersdrawingsimulationUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure);
            app.DirectcomputationTab = uitab(app.TabGroup);
            app.DirectcomputationTab.Title = 'Direct computation';
            app.UIAxes = uiaxes(app.DirectcomputationTab);
            xlabel(app.UIAxes, 'z (m)')
            ylabel(app.UIAxes, 'V (m/min)')
            app.GrandeurreprsenterDropDown = uidropdown(app.DirectcomputationTab);
            app.GrandeurreprsenterDropDown.Items = {'V', 'D', 'd', 'Dc', 'dc', 't', 'Dcore', 'dc/Dc', 'Temperature', 'Draw tension', 'Rc/Rcontact', 'Schematic'};
            app.GrandeurreprsenterDropDown.ValueChangedFcn = createCallbackFcn(app, @GrandeurreprsenterDropDownValueChanged, true);
            app.GrandeurreprsenterDropDown.Value = 'V';
            app.RunButton = uibutton(app.DirectcomputationTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.zmSliderLabel = uilabel(app.DirectcomputationTab);
            app.zmSliderLabel.Visible = 'off';
            app.zmSliderLabel.Text = {'z (m)'; ''};
            app.zmSlider = uislider(app.DirectcomputationTab);
            app.zmSlider.Limits = [0 1];
            app.zmSlider.ValueChangingFcn = createCallbackFcn(app, @zmSliderValueChanging, true);
            app.zmSlider.Visible = 'off';
            app.Image = uiimage(app.DirectcomputationTab);
            app.Image.Tooltip = {'https://fibertech.univ-lille.fr/en/'};
            app.Image.URL = 'https://fibertech.univ-lille.fr/en/';
            app.Image.AltText = 'https://fibertech.univ-lille.fr/en/';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.FurnacetemperatureprofileEditFieldLabel = uilabel(app.DirectcomputationTab);
            app.FurnacetemperatureprofileEditFieldLabel.Tooltip = {'Temperatue profile of the furnace (function of z)'};
            app.FurnacetemperatureprofileEditFieldLabel.Text = 'Furnace temperature profile';
            app.FurnacetemperatureprofileEditField = uieditfield(app.DirectcomputationTab, 'text');
            app.FurnacetemperatureprofileEditField.ValueChangedFcn = createCallbackFcn(app, @FurnacetemperatureprofileEditFieldValueChanged, true);
            app.FurnacetemperatureprofileEditField.Tooltip = {'T/Tmax as a function of z'};
            app.FurnacetemperatureprofileEditField.Value = 'exp(-(z-0.04)^2/2/0.06^2)';
            app.Button = uibutton(app.DirectcomputationTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'github-mark.png');
            app.Button.Tooltip = {'https://github.com/ovanvincq/HARF-drawing'};
            app.Button.Text = '';
            app.InputPanel_2 = uipanel(app.DirectcomputationTab);
            app.InputPanel_2.Title = 'Input';
            app.PcoremBarEditFieldLabel = uilabel(app.InputPanel_2);
            app.PcoremBarEditFieldLabel.Tooltip = {'Pressure applied in the core'};
            app.PcoremBarEditFieldLabel.Text = 'Pcore (mBar)';
            app.PcoremBarEditField = uieditfield(app.InputPanel_2, 'numeric');
            app.PcoremBarEditField.Tooltip = {''};
            app.PcoremBarEditField.Value = 15.6;
            app.FeedspeedmmminEditFieldLabel = uilabel(app.InputPanel_2);
            app.FeedspeedmmminEditFieldLabel.Tooltip = {'Feed speed'};
            app.FeedspeedmmminEditFieldLabel.Text = 'Feed speed (mm/min)';
            app.FeedspeedmmminEditField = uieditfield(app.InputPanel_2, 'numeric');
            app.FeedspeedmmminEditField.Limits = [0 Inf];
            app.FeedspeedmmminEditField.Value = 3;
            app.D0mmEditFieldLabel = uilabel(app.InputPanel_2);
            app.D0mmEditFieldLabel.Tooltip = {'Initial outer diameter of the jacket'};
            app.D0mmEditFieldLabel.Text = 'D0 (mm)';
            app.D0mmEditField = uieditfield(app.InputPanel_2, 'numeric');
            app.D0mmEditField.Limits = [0 Inf];
            app.D0mmEditField.Value = 8.3556;
            app.d0mmEditFieldLabel = uilabel(app.InputPanel_2);
            app.d0mmEditFieldLabel.Tooltip = {'Initial inner diameter of the jacket'};
            app.d0mmEditFieldLabel.Text = 'd0 (mm)';
            app.d0mmEditField = uieditfield(app.InputPanel_2, 'numeric');
            app.d0mmEditField.Limits = [0 Inf];
            app.d0mmEditField.Value = 2.4;
            app.Dc0mmEditFieldLabel = uilabel(app.InputPanel_2);
            app.Dc0mmEditFieldLabel.Tooltip = {'Initial outer diameter of the capillary tubes'};
            app.Dc0mmEditFieldLabel.Text = 'Dc0 (mm)';
            app.Dc0mmEditField = uieditfield(app.InputPanel_2, 'numeric');
            app.Dc0mmEditField.Limits = [0 Inf];
            app.Dc0mmEditField.ValueChangedFcn = createCallbackFcn(app, @Dc0mmEditFieldValueChanged, true);
            app.Dc0mmEditField.Value = 0.48;
            app.dc0mmEditFieldLabel = uilabel(app.InputPanel_2);
            app.dc0mmEditFieldLabel.Tooltip = {'Initial inner diameter of the capillary tubes'};
            app.dc0mmEditFieldLabel.Text = 'dc0 (mm)';
            app.dc0mmEditField = uieditfield(app.InputPanel_2, 'numeric');
            app.dc0mmEditField.Limits = [0 Inf];
            app.dc0mmEditField.ValueChangedFcn = createCallbackFcn(app, @dc0mmEditFieldValueChanged, true);
            app.dc0mmEditField.Value = 0.3936;
            app.LmEditFieldLabel = uilabel(app.InputPanel_2);
            app.LmEditFieldLabel.Tooltip = {'Total length'};
            app.LmEditFieldLabel.Text = 'L (m)';
            app.LmEditField = uieditfield(app.InputPanel_2, 'numeric');
            app.LmEditField.Limits = [0.1 Inf];
            app.LmEditField.ValueChangedFcn = createCallbackFcn(app, @LmEditFieldValueChanged, true);
            app.LmEditField.Value = 0.1;
            app.DrawtensiongEditFieldLabel = uilabel(app.InputPanel_2);
            app.DrawtensiongEditFieldLabel.Tooltip = {'Draw tension'};
            app.DrawtensiongEditFieldLabel.Text = 'Draw tension (g)';
            app.DrawtensiongEditField = uieditfield(app.InputPanel_2, 'numeric');
            app.DrawtensiongEditField.Limits = [0 Inf];
            app.DrawtensiongEditField.Value = 84;
            app.NumberofcapillariesEditFieldLabel = uilabel(app.InputPanel_2);
            app.NumberofcapillariesEditFieldLabel.Tooltip = {'Number of capillary tubes'};
            app.NumberofcapillariesEditFieldLabel.Text = 'Number of capillaries';
            app.NumberofcapillariesEditField = uieditfield(app.InputPanel_2, 'numeric');
            app.NumberofcapillariesEditField.Limits = [2 Inf];
            app.NumberofcapillariesEditField.RoundFractionalValues = 'on';
            app.NumberofcapillariesEditField.Value = 7;
            app.TmaxCEditFieldLabel = uilabel(app.InputPanel_2);
            app.TmaxCEditFieldLabel.Tooltip = {'Maximum temperature of the furnace'};
            app.TmaxCEditFieldLabel.Text = 'Tmax (°C)';
            app.TmaxCEditField = uieditfield(app.InputPanel_2, 'numeric');
            app.TmaxCEditField.Limits = [1000 Inf];
            app.TmaxCEditField.ValueDisplayFormat = '%.4f';
            app.TmaxCEditField.ValueChangedFcn = createCallbackFcn(app, @TmaxCEditFieldValueChanged, true);
            app.TmaxCEditField.Value = 1806.9;
            app.PcapmbarLabel = uilabel(app.InputPanel_2);
            app.PcapmbarLabel.Tooltip = {'Pressure of the capillary tubes'};
            app.PcapmbarLabel.Text = 'Pcap (mbar)';
            app.DeltaPEditField = uieditfield(app.InputPanel_2, 'numeric');
            app.DeltaPEditField.Value = 63.4;
            app.FurnacelengthmEditFieldLabel = uilabel(app.InputPanel_2);
            app.FurnacelengthmEditFieldLabel.Tooltip = {'Length of the furnace'};
            app.FurnacelengthmEditFieldLabel.Text = 'Furnace length (m)';
            app.FurnacelengthmEditField = uieditfield(app.InputPanel_2, 'numeric');
            app.FurnacelengthmEditField.Limits = [0 Inf];
            app.FurnacelengthmEditField.ValueChangedFcn = createCallbackFcn(app, @FurnacelengthmEditFieldValueChanged, true);
            app.FurnacelengthmEditField.Value = 0.065;
            app.OutputPanel_2 = uipanel(app.DirectcomputationTab);
            app.OutputPanel_2.Title = 'Output';
            app.DfLabel = uilabel(app.OutputPanel_2);
            app.DfLabel.Tooltip = {'Final outer diameter of the jacket'};
            app.DfLabel.Text = 'Df =';
            app.dfLabel = uilabel(app.OutputPanel_2);
            app.dfLabel.Tooltip = {'Final inner diameter of the jacket'};
            app.dfLabel.Text = 'df =';
            app.DcfLabel = uilabel(app.OutputPanel_2);
            app.DcfLabel.Tooltip = {'Final outer diameter of the capillary tubes'};
            app.DcfLabel.Text = 'Dcf = ';
            app.dcfLabel = uilabel(app.OutputPanel_2);
            app.dcfLabel.Tooltip = {'Final inner diameter of the capillary tubes'};
            app.dcfLabel.Text = 'dcf =';
            app.DcoreLabel = uilabel(app.OutputPanel_2);
            app.DcoreLabel.Tooltip = {'Final core diameter'};
            app.DcoreLabel.Text = 'Dcore =';
            app.tfLabel = uilabel(app.OutputPanel_2);
            app.tfLabel.Tooltip = {'Final thickness of capillary tubes'};
            app.tfLabel.Text = 'tf = ';
            app.maxRcRcontactLabel = uilabel(app.OutputPanel_2);
            app.maxRcRcontactLabel.Text = 'max(Rc/Rcontact) =';
            app.VfLabel = uilabel(app.OutputPanel_2);
            app.VfLabel.Tooltip = {'Draw speed'};
            app.VfLabel.Text = 'Vf = ';
            app.dcfDcfLabel_2 = uilabel(app.OutputPanel_2);
            app.dcfDcfLabel_2.Text = 'dcf/Dcf =';
            app.dc0Dc0Label_2 = uilabel(app.OutputPanel_2);
            app.dc0Dc0Label_2.Text = 'dc0/Dc0 =';
            app.SaveresultsButton = uibutton(app.OutputPanel_2, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Enable = 'off';
            app.SaveresultsButton.Text = 'Save results';
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.UIAxes_2 = uiaxes(app.OptimizationTab);
            xlabel(app.UIAxes_2, 'z (m)')
            ylabel(app.UIAxes_2, 'V (m/min)')
            app.InputPanel = uipanel(app.OptimizationTab);
            app.InputPanel.Title = 'Input';
            app.FeedspeedmmminLabel = uilabel(app.InputPanel);
            app.FeedspeedmmminLabel.Tooltip = {'Feed speed'};
            app.FeedspeedmmminLabel.Text = 'Feed speed (mm/min)';
            app.OptimViEditField = uieditfield(app.InputPanel, 'numeric');
            app.OptimViEditField.Limits = [0 Inf];
            app.OptimViEditField.ValueChangedFcn = createCallbackFcn(app, @OptimViEditFieldValueChanged, true);
            app.OptimViEditField.Value = 3;
            app.DrawtensiongLabel = uilabel(app.InputPanel);
            app.DrawtensiongLabel.Tooltip = {'Draw tension'};
            app.DrawtensiongLabel.Text = 'Draw tension (g)';
            app.OptimTensionEditField = uieditfield(app.InputPanel, 'numeric');
            app.OptimTensionEditField.Limits = [0 Inf];
            app.OptimTensionEditField.Value = 84;
            app.D0mmEditField_2Label = uilabel(app.InputPanel);
            app.D0mmEditField_2Label.Tooltip = {'Initial outer diameter of the jacket'};
            app.D0mmEditField_2Label.Text = 'D0 (mm)';
            app.OptimD0EditField = uieditfield(app.InputPanel, 'numeric');
            app.OptimD0EditField.Limits = [0 Inf];
            app.OptimD0EditField.ValueChangedFcn = createCallbackFcn(app, @OptimD0EditFieldValueChanged, true);
            app.OptimD0EditField.Value = 8.3556;
            app.d0mmLabel = uilabel(app.InputPanel);
            app.d0mmLabel.Tooltip = {'Initial inner diameter of the jacket'};
            app.d0mmLabel.Text = 'd0 (mm)';
            app.Optimd0EditField = uieditfield(app.InputPanel, 'numeric');
            app.Optimd0EditField.Limits = [0 Inf];
            app.Optimd0EditField.ValueChangedFcn = createCallbackFcn(app, @Optimd0EditFieldValueChanged, true);
            app.Optimd0EditField.Value = 2.4;
            app.Dc0mmLabel = uilabel(app.InputPanel);
            app.Dc0mmLabel.Tooltip = {'Initial outer diameter of the capillary tubes'};
            app.Dc0mmLabel.Text = 'Dc0 (mm)';
            app.OptimDc0EditField = uieditfield(app.InputPanel, 'numeric');
            app.OptimDc0EditField.Limits = [0 Inf];
            app.OptimDc0EditField.ValueChangedFcn = createCallbackFcn(app, @OptimDc0EditFieldValueChanged, true);
            app.OptimDc0EditField.Value = 0.48;
            app.dc0mmLabel = uilabel(app.InputPanel);
            app.dc0mmLabel.Tooltip = {'Initial inner diameter of the capillary tubes'};
            app.dc0mmLabel.Text = 'dc0 (mm)';
            app.Optimdc0EditField = uieditfield(app.InputPanel, 'numeric');
            app.Optimdc0EditField.Limits = [0 Inf];
            app.Optimdc0EditField.ValueChangedFcn = createCallbackFcn(app, @Optimdc0EditFieldValueChanged, true);
            app.Optimdc0EditField.Value = 0.3936;
            app.LmLabel = uilabel(app.InputPanel);
            app.LmLabel.Tooltip = {'Total length'};
            app.LmLabel.Text = 'L (m)';
            app.OptimLEditField = uieditfield(app.InputPanel, 'numeric');
            app.OptimLEditField.Limits = [0.1 Inf];
            app.OptimLEditField.ValueChangedFcn = createCallbackFcn(app, @OptimLEditFieldValueChanged, true);
            app.OptimLEditField.Value = 0.5;
            app.NumberofcapillariesLabel = uilabel(app.InputPanel);
            app.NumberofcapillariesLabel.Tooltip = {'Number of capillary tubes'};
            app.NumberofcapillariesLabel.Text = 'Number of capillaries';
            app.OptimNbEditField = uieditfield(app.InputPanel, 'numeric');
            app.OptimNbEditField.Limits = [2 Inf];
            app.OptimNbEditField.RoundFractionalValues = 'on';
            app.OptimNbEditField.Value = 7;
            app.dc0Dc0Label = uilabel(app.InputPanel);
            app.dc0Dc0Label.Text = 'dc0/Dc0 =';
            app.FurnacelengthmEditField_2Label = uilabel(app.InputPanel);
            app.FurnacelengthmEditField_2Label.Tooltip = {'Length of the furnace'};
            app.FurnacelengthmEditField_2Label.Text = 'Furnace length (m)';
            app.FurnacelengthmEditField_2 = uieditfield(app.InputPanel, 'numeric');
            app.FurnacelengthmEditField_2.Limits = [0 Inf];
            app.FurnacelengthmEditField_2.ValueChangedFcn = createCallbackFcn(app, @FurnacelengthmEditField_2ValueChanged, true);
            app.FurnacelengthmEditField_2.Value = 0.065;
            app.ObjectivePanel = uipanel(app.OptimizationTab);
            app.ObjectivePanel.Title = 'Objective';
            app.tfmLabel = uilabel(app.ObjectivePanel);
            app.tfmLabel.Tooltip = {'Final thickness of the capillary tubes'};
            app.tfmLabel.Text = 'tf (µm)';
            app.OptimtfEditField = uieditfield(app.ObjectivePanel, 'numeric');
            app.OptimtfEditField.Limits = [0 Inf];
            app.OptimtfEditField.ValueChangedFcn = createCallbackFcn(app, @OptimtfEditFieldValueChanged, true);
            app.OptimtfEditField.Value = 3.5;
            app.DcfmLabel = uilabel(app.ObjectivePanel);
            app.DcfmLabel.Tooltip = {'Final outer diameter of the capillary tubes'};
            app.DcfmLabel.Text = 'Dcf (µm)';
            app.OptimDcfEditField = uieditfield(app.ObjectivePanel, 'numeric');
            app.OptimDcfEditField.ValueChangedFcn = createCallbackFcn(app, @OptimDcfEditFieldValueChanged, true);
            app.OptimDcfEditField.Value = 110;
            app.DcoremEditFieldLabel = uilabel(app.ObjectivePanel);
            app.DcoremEditFieldLabel.Tooltip = {'Final core diameter'};
            app.DcoremEditFieldLabel.Text = 'Dcore (µm)';
            app.OptimDcoreEditField = uieditfield(app.ObjectivePanel, 'numeric');
            app.OptimDcoreEditField.Limits = [0 Inf];
            app.OptimDcoreEditField.ValueChangedFcn = createCallbackFcn(app, @OptimDcoreEditFieldValueChanged, true);
            app.OptimDcoreEditField.Value = 156;
            app.OptimDfLabel = uilabel(app.ObjectivePanel);
            app.OptimDfLabel.Tooltip = {'Final outer diameter of the jacket'};
            app.OptimDfLabel.Text = 'Df =';
            app.OptimdfLabel = uilabel(app.ObjectivePanel);
            app.OptimdfLabel.Tooltip = {'Final inner diameter of the jacket'};
            app.OptimdfLabel.Text = 'df =';
            app.OptimdcfLabel = uilabel(app.ObjectivePanel);
            app.OptimdcfLabel.Tooltip = {'Final inner diameter of the capillary tubes'};
            app.OptimdcfLabel.Text = 'dcf =';
            app.OptimVfLabel = uilabel(app.ObjectivePanel);
            app.OptimVfLabel.Tooltip = {'Draw speed'};
            app.OptimVfLabel.Text = 'Vf =';
            app.dcfDcfLabel = uilabel(app.ObjectivePanel);
            app.dcfDcfLabel.Text = 'dcf/Dcf =';
            app.zmSlider_2Label = uilabel(app.OptimizationTab);
            app.zmSlider_2Label.Visible = 'off';
            app.zmSlider_2Label.Text = {'z (m)'; ''};
            app.zmSlider_2 = uislider(app.OptimizationTab);
            app.zmSlider_2.Limits = [0 1];
            app.zmSlider_2.ValueChangingFcn = createCallbackFcn(app, @zmSlider_2ValueChanging, true);
            app.zmSlider_2.Visible = 'off';
            app.GrandeurreprsenterDropDown_2 = uidropdown(app.OptimizationTab);
            app.GrandeurreprsenterDropDown_2.Items = {'V', 'D', 'd', 'Dc', 'dc', 't', 'Dcore', 'dc/Dc', 'Temperature', 'Draw tension', 'Rc/Rcontact', 'Schematic'};
            app.GrandeurreprsenterDropDown_2.ValueChangedFcn = createCallbackFcn(app, @GrandeurreprsenterDropDown_2ValueChanged, true);
            app.GrandeurreprsenterDropDown_2.Value = 'V';
            app.OutputPanel = uipanel(app.OptimizationTab);
            app.OutputPanel.Title = 'Output';
            app.OptimmaxLabel = uilabel(app.OutputPanel);
            app.OptimmaxLabel.Text = 'max(Rc/Rcontact) = ';
            app.OptimdpLabel = uilabel(app.OutputPanel);
            app.OptimdpLabel.Text = 'Pcap =';
            app.OptimPcoreLabel = uilabel(app.OutputPanel);
            app.OptimPcoreLabel.Text = 'Pcore = ';
            app.OptimTmaxLabel = uilabel(app.OutputPanel);
            app.OptimTmaxLabel.Text = 'Tmax = ';
            app.SaveresultsButton_2 = uibutton(app.OutputPanel, 'push');
            app.SaveresultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButton_2Pushed, true);
            app.SaveresultsButton_2.Enable = 'off';
            app.SaveresultsButton_2.Text = 'Save results';
            app.Image_2 = uiimage(app.OptimizationTab);
            app.Image_2.Tooltip = {'https://fibertech.univ-lille.fr/en/'};
            app.Image_2.URL = 'https://fibertech.univ-lille.fr/en/';
            app.Image_2.AltText = 'https://fibertech.univ-lille.fr/en/';
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.ApproximatevaluesPanel = uipanel(app.OptimizationTab);
            app.ApproximatevaluesPanel.Title = 'Approximate values';
            app.TmaxCLabel = uilabel(app.ApproximatevaluesPanel);
            app.TmaxCLabel.Tooltip = {'Maximum temperature of the furnace'};
            app.TmaxCLabel.Text = 'Tmax(°C)';
            app.minEditFieldLabel = uilabel(app.ApproximatevaluesPanel);
            app.minEditFieldLabel.Text = 'min';
            app.OptimTmaxminEditField = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimTmaxminEditField.Limits = [1500 2500];
            app.OptimTmaxminEditField.ValueChangedFcn = createCallbackFcn(app, @OptimTmaxminEditFieldValueChanged, true);
            app.OptimTmaxminEditField.Value = 1750;
            app.maxEditFieldLabel = uilabel(app.ApproximatevaluesPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.OptimTmaxmaxEditField = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimTmaxmaxEditField.Limits = [1500 2500];
            app.OptimTmaxmaxEditField.ValueChangedFcn = createCallbackFcn(app, @OptimTmaxmaxEditFieldValueChanged, true);
            app.OptimTmaxmaxEditField.Value = 1850;
            app.PcorembarLabel = uilabel(app.ApproximatevaluesPanel);
            app.PcorembarLabel.Tooltip = {'Pressure applied in the core'};
            app.PcorembarLabel.Text = 'Pcore (mbar)';
            app.minEditFieldLabel_2 = uilabel(app.ApproximatevaluesPanel);
            app.minEditFieldLabel_2.Text = 'min';
            app.OptimPcoreminEditField = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimPcoreminEditField.Value = 10;
            app.maxEditFieldLabel_2 = uilabel(app.ApproximatevaluesPanel);
            app.maxEditFieldLabel_2.Text = 'max';
            app.OptimPcoremaxEditField = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimPcoremaxEditField.Value = 20;
            app.minEditFieldLabel_3 = uilabel(app.ApproximatevaluesPanel);
            app.minEditFieldLabel_3.Text = 'min';
            app.OptimDeltaPminEditField = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimDeltaPminEditField.Value = 50;
            app.maxLabel = uilabel(app.ApproximatevaluesPanel);
            app.maxLabel.Text = 'max';
            app.OptimDeltaPmaxEditField = uieditfield(app.ApproximatevaluesPanel, 'numeric');
            app.OptimDeltaPmaxEditField.Value = 150;
            app.DeltaPmbarLabel_2 = uilabel(app.ApproximatevaluesPanel);
            app.DeltaPmbarLabel_2.Tooltip = {'Pressure of the capillary tubes'};
            app.DeltaPmbarLabel_2.Text = 'Pcap (mbar)';
            app.RunButton_2 = uibutton(app.OptimizationTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.FurnacetemperatureprofileEditField_2Label = uilabel(app.OptimizationTab);
            app.FurnacetemperatureprofileEditField_2Label.Tooltip = {'Temperature profile of the furnace (function of z)'};
            app.FurnacetemperatureprofileEditField_2Label.Text = 'Furnace temperature profile';
            app.FurnacetemperatureprofileEditField_2 = uieditfield(app.OptimizationTab, 'text');
            app.FurnacetemperatureprofileEditField_2.ValueChangedFcn = createCallbackFcn(app, @FurnacetemperatureprofileEditField_2ValueChanged, true);
            app.FurnacetemperatureprofileEditField_2.Tooltip = {'T/Tmax as a function of z'};
            app.FurnacetemperatureprofileEditField_2.Value = 'exp(-(z-0.04)^2/2/0.06^2)';
            app.Button_2 = uibutton(app.OptimizationTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'github-mark.png');
            app.Button_2.Tooltip = {'https://github.com/ovanvincq/HARF-drawing'};
            app.Button_2.Text = '';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.UIAxes_3 = uiaxes(app.ParametersTab);
            xlabel(app.UIAxes_3, 'T (K)')
            ylabel(app.UIAxes_3, 'Volumic mass (kg/m^3)')
            app.UIAxes_3.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MaterialpropertiesFunctionsofTKPanel = uipanel(app.ParametersTab);
            app.MaterialpropertiesFunctionsofTKPanel.Title = 'Material properties - Functions of T(K)';
            app.Volumicmasskgm3EditFieldLabel = uilabel(app.MaterialpropertiesFunctionsofTKPanel);
            app.Volumicmasskgm3EditFieldLabel.Text = 'Volumic mass (kg/m^3)';
            app.Volumicmasskgm3EditField = uieditfield(app.MaterialpropertiesFunctionsofTKPanel, 'text');
            app.Volumicmasskgm3EditField.ValueChangedFcn = createCallbackFcn(app, @Volumicmasskgm3EditFieldValueChanged, true);
            app.Volumicmasskgm3EditField.Value = '2200';
            app.SurfacetensionNmEditFieldLabel = uilabel(app.MaterialpropertiesFunctionsofTKPanel);
            app.SurfacetensionNmEditFieldLabel.Text = 'Surface tension (N/m)';
            app.SurfacetensionNmEditField = uieditfield(app.MaterialpropertiesFunctionsofTKPanel, 'text');
            app.SurfacetensionNmEditField.ValueChangedFcn = createCallbackFcn(app, @SurfacetensionNmEditFieldValueChanged, true);
            app.SurfacetensionNmEditField.Value = '0.3';
            app.SpecificheatJkgKEditFieldLabel = uilabel(app.MaterialpropertiesFunctionsofTKPanel);
            app.SpecificheatJkgKEditFieldLabel.Text = 'Specific heat (J/kg/K)';
            app.SpecificheatJkgKEditField = uieditfield(app.MaterialpropertiesFunctionsofTKPanel, 'text');
            app.SpecificheatJkgKEditField.ValueChangedFcn = createCallbackFcn(app, @SpecificheatJkgKEditFieldValueChanged, true);
            app.SpecificheatJkgKEditField.Value = '-1.9202379E-11*(9*min(max(310.75,T),1669.67)/5-459.4)^4+8.9608033E-8*(9*min(max(310.75,T),1669.67)/5-459.4)^3-2.0008399E-4*(9*min(max(310.75,T),1669.67)/5-459.4)^2+0.59235423*(9*min(max(310.75,T),1669.67)/5-459.4)+6.2175046E2';
            app.ViscosityNsmEditFieldLabel = uilabel(app.MaterialpropertiesFunctionsofTKPanel);
            app.ViscosityNsmEditFieldLabel.Text = 'Viscosity (N.s/m²)';
            app.ViscosityNsmEditField = uieditfield(app.MaterialpropertiesFunctionsofTKPanel, 'text');
            app.ViscosityNsmEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityNsmEditFieldValueChanged, true);
            app.ViscosityNsmEditField.Value = '10^(-7.946)*exp(66337/T)';
            app.Volumicemissivity1mEditFieldLabel = uilabel(app.MaterialpropertiesFunctionsofTKPanel);
            app.Volumicemissivity1mEditFieldLabel.Text = 'Volumic emissivity (1/m)';
            app.Volumicemissivity1mEditField = uieditfield(app.MaterialpropertiesFunctionsofTKPanel, 'text');
            app.Volumicemissivity1mEditField.ValueChangedFcn = createCallbackFcn(app, @Volumicemissivity1mEditFieldValueChanged, true);
            app.Volumicemissivity1mEditField.Value = '0.885';
            app.ConstantsPanel = uipanel(app.ParametersTab);
            app.ConstantsPanel.Title = 'Constants';
            app.StefanBoltzmannconstantWmK4EditFieldLabel = uilabel(app.ConstantsPanel);
            app.StefanBoltzmannconstantWmK4EditFieldLabel.Text = 'Stefan-Boltzmann constant (W/m²/K^4)';
            app.StefanBoltzmannconstantWmK4EditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.StefanBoltzmannconstantWmK4EditField.Value = 5.67e-08;
            app.NewtonheattransfercoefficientWmKEditFieldLabel = uilabel(app.ConstantsPanel);
            app.NewtonheattransfercoefficientWmKEditFieldLabel.Text = 'Newton heat transfer coefficient (W/m²/K)';
            app.NewtonheattransfercoefficientWmKEditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.NewtonheattransfercoefficientWmKEditField.Value = 100;
            app.GravitationalaccelerationmsEditFieldLabel = uilabel(app.ConstantsPanel);
            app.GravitationalaccelerationmsEditFieldLabel.Text = 'Gravitational acceleration (m/s²)';
            app.GravitationalaccelerationmsEditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.GravitationalaccelerationmsEditField.Value = 9.81;
            app.Differenceoftemperaturebetweenthefurnaceandthepreformatz0KLabel = uilabel(app.ConstantsPanel);
            app.Differenceoftemperaturebetweenthefurnaceandthepreformatz0KLabel.Text = 'T(furnace)-T(preform) at z=0 (K)';
            app.EditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.EditField.Value = 500;
            app.ExternaltemperatureCEditFieldLabel = uilabel(app.ConstantsPanel);
            app.ExternaltemperatureCEditFieldLabel.Text = 'External temperature (°C)';
            app.ExternaltemperatureCEditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.ExternaltemperatureCEditField.Value = 20;
            app.GrandeurreprsenterDropDown_3 = uidropdown(app.ParametersTab);
            app.GrandeurreprsenterDropDown_3.Items = {'Volumic mass', 'Surface tension', 'Specific heat', 'Viscosity', 'Volumic emissivity'};
            app.GrandeurreprsenterDropDown_3.ValueChangedFcn = createCallbackFcn(app, @GrandeurreprsenterDropDown_3ValueChanged, true);
            app.GrandeurreprsenterDropDown_3.Value = 'Volumic mass';
            app.Image_3 = uiimage(app.ParametersTab);
            app.Image_3.Tooltip = {'https://fibertech.univ-lille.fr/en/'};
            app.Image_3.URL = 'https://fibertech.univ-lille.fr/en/';
            app.Image_3.AltText = 'https://fibertech.univ-lille.fr/en/';
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.Button_3 = uibutton(app.ParametersTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'github-mark.png');
            app.Button_3.Tooltip = {'https://github.com/ovanvincq/HARF-drawing'};
            app.Button_3.Text = '';
            % Show the figure after all components are created
            app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HARF_drawing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TubularantiresonanthollowcorefibersdrawingsimulationUIFigure)
        end
    end
end